classdef ARBOTestbed_serialRecorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        PortDropDownLabel  matlab.ui.control.Label
        PortDropDown       matlab.ui.control.DropDown
        ScanPortsButton    matlab.ui.control.Button
        UITable            matlab.ui.control.Table
        Switch             matlab.ui.control.Switch
        PlotDataButton     matlab.ui.control.StateButton
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_4           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        serialPort
        maxPoints = 500
        numChannels = 4
        sampleBytes = 28
        colors = lines(4)
        fileName
        count
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function handleSerial(app,src,event)
            %             data = readline(app.serialPort); % ch1,ch2,ch3,ch4,filename
            data = read(app.serialPort,app.sampleBytes,"int8");
            %             disp(data);
            addpoints(app.UIAxes.Children(1),[app.count:app.count+app.sampleBytes-1],data);
            %             parts = strsplit(data,",");
            %             if numel(char(parts(end)))>1 && strcmp(char(parts(end)),app.fileName) == 0
            %                 app.fileName = char(parts(end));
            %                 app.UITable.Data = [app.UITable.Data;datestr(now,'HH:MM:SS'),parts(end)];
            %             end
            %
            %             addpoints(app.UIAxes.Children(1),app.count,str2double(parts(1)));
            %             addpoints(app.UIAxes_2.Children(1),app.count,str2double(parts(2)));
            %             addpoints(app.UIAxes_3.Children(1),app.count,str2double(parts(3)));
            %             addpoints(app.UIAxes_4.Children(1),app.count,str2double(parts(4)));
            %             if mod(app.count,10) == 0
            %                 drawnow;
            %             end
            app.count = app.count + app.sampleBytes;
            if app.count >= app.maxPoints
                app.UIAxes.XLim = app.UIAxes.XLim + app.sampleBytes;
                app.UIAxes_2.XLim = app.UIAxes_2.XLim + app.sampleBytes;
                app.UIAxes_3.XLim = app.UIAxes_3.XLim + app.sampleBytes;
                app.UIAxes_4.XLim = app.UIAxes_4.XLim + app.sampleBytes;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanPortsButton
        function scanPorts(app, event)
            ports = serialportlist("available");
            usePorts = [];
            for iPort = 1:numel(ports)
                if contains(ports(iPort),"cu.") & ~contains(ports(iPort),"Bluetooth")
                    usePorts = [usePorts iPort];
                end
            end
            if numel(usePorts) > 0
                app.PortDropDown.Items = ports(usePorts);
                app.PortDropDown.Enable = true;
                app.Switch.Enable = true;
            end
        end

        % Value changed function: Switch
        function connect(app, event)
            if strcmp(app.Switch.Value,'On')
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                app.serialPort = serialport(app.PortDropDown.Value,115200);
                app.PlotDataButton.Enable = true;
            else
                app.PlotDataButton.Value = false;
                app.PlotDataButton.Enable = false;
                configureCallback(app.serialPort,"off");
            end
        end

        % Value changed function: PlotDataButton
        function plotData(app, event)
            if app.PlotDataButton.Value
                % clear old data
                if ~isempty(app.UIAxes.Children)
                    delete(app.UIAxes.Children(1));
                    delete(app.UIAxes_2.Children(1));
                    delete(app.UIAxes_3.Children(1));
                    delete(app.UIAxes_4.Children(1));
                end
                % initialize plots
                animatedline(0,0,'Color',app.colors(1,:),'LineWidth',1,'Parent',...
                    app.UIAxes,'MaximumNumPoints',app.maxPoints);
                animatedline(0,0,'Color',app.colors(2,:),'LineWidth',1,'Parent',...
                    app.UIAxes_2,'MaximumNumPoints',app.maxPoints);
                animatedline(0,0,'Color',app.colors(3,:),'LineWidth',1,'Parent',...
                    app.UIAxes_3,'MaximumNumPoints',app.maxPoints);
                animatedline(0,0,'Color',app.colors(4,:),'LineWidth',1,'Parent',...
                    app.UIAxes_4,'MaximumNumPoints',app.maxPoints);
                app.UIAxes.XLim = [1 app.maxPoints];
                app.UIAxes_2.XLim = [1 app.maxPoints];
                app.UIAxes_3.XLim = [1 app.maxPoints];
                app.UIAxes_4.XLim = [1 app.maxPoints];
                app.count = 1;
                % then turn on the callback
                configureCallback(app.serialPort,"byte",1,@app.handleSerial); % or terminator
            else
                configureCallback(app.serialPort,"off");
                
            end
        end

        % Callback function
        function toggleBias(app, event)
%             clearpoints(app.UIAxes.Children(1));
%             clearpoints(app.UIAxes_2.Children(1));
%             clearpoints(app.UIAxes_3.Children(1));
%             clearpoints(app.UIAxes_4.Children(1));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {258, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PortDropDownLabel = uilabel(app.LeftPanel);
            app.PortDropDownLabel.Enable = 'off';
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.LeftPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Enable = 'off';
            app.PortDropDown.Value = {};
            app.ScanPortsButton = uibutton(app.LeftPanel, 'push');
            app.ScanPortsButton.ButtonPushedFcn = createCallbackFcn(app, @scanPorts, true);
            app.ScanPortsButton.Text = 'Scan Ports';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Time'; 'Name'};
            app.UITable.RowName = {};
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @connect, true);
            app.Switch.Enable = 'off';
            app.PlotDataButton = uibutton(app.LeftPanel, 'state');
            app.PlotDataButton.ValueChangedFcn = createCallbackFcn(app, @plotData, true);
            app.PlotDataButton.Enable = 'off';
            app.PlotDataButton.Text = 'Plot Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Ch1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Ch2')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Ch3')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Ch4')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARBOTestbed_serialRecorder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end